classdef databasecreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BatteryAttributesPanel         matlab.ui.container.Panel
        BatteryMasskgEditField         matlab.ui.control.NumericEditField
        BatteryMasskgEditFieldLabel    matlab.ui.control.Label
        CapacitymAhEditField           matlab.ui.control.NumericEditField
        CapacitymAhEditFieldLabel      matlab.ui.control.Label
        GeneralPanel                   matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        SelectFileButton               matlab.ui.control.Button
        SelectFileLabel                matlab.ui.control.Label
        NewRobotDatabaseLabel          matlab.ui.control.Label
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        RobotNameEditField             matlab.ui.control.EditField
        RobotNameEditFieldLabel        matlab.ui.control.Label
        MotorAttributesPanel           matlab.ui.container.Panel
        NoloadSpeedrpmEditField        matlab.ui.control.NumericEditField
        NoloadSpeedrpmEditFieldLabel   matlab.ui.control.Label
        StallTorqueNmEditField         matlab.ui.control.NumericEditField
        StallTorqueNmEditFieldLabel    matlab.ui.control.Label
        MotorMasskgEditField           matlab.ui.control.NumericEditField
        MotorMasskgEditFieldLabel      matlab.ui.control.Label
        PleaseprovidetherelevantdataLabel  matlab.ui.control.Label
        SavetoFileButton               matlab.ui.control.Button
        PhysicalCharacteristicsPanel   matlab.ui.container.Panel
        SensorArrayMasskgEditField     matlab.ui.control.NumericEditField
        SensorArrayMasskgEditFieldLabel  matlab.ui.control.Label
        SingleLegMasskgEditField       matlab.ui.control.NumericEditField
        SingleLegMasskgEditFieldLabel  matlab.ui.control.Label
        TotalMasskgEditField           matlab.ui.control.NumericEditField
        TotalMasskgEditFieldLabel      matlab.ui.control.Label
        LegThicknesscmEditField        matlab.ui.control.NumericEditField
        LegThicknesscmEditFieldLabel   matlab.ui.control.Label
        LegWidthcmEditField            matlab.ui.control.NumericEditField
        LegWidthcmEditFieldLabel       matlab.ui.control.Label
        LegLengthcmEditField           matlab.ui.control.NumericEditField
        LegLengthcmEditFieldLabel      matlab.ui.control.Label
        BodyHeightcmEditField          matlab.ui.control.NumericEditField
        BodyHeightcmEditFieldLabel     matlab.ui.control.Label
        BodyWidthcmEditField           matlab.ui.control.NumericEditField
        BodyWidthcmEditFieldLabel      matlab.ui.control.Label
        BodyLengthcmEditField          matlab.ui.control.NumericEditField
        BodyLengthcmEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        newrobot
        logdbcreator
    end
    
    properties (Access = private)
        mockuppart3 % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.mockuppart3 = caller;
            app.newrobot.body.length = 1;
            app.newrobot.leg.height = 1;
            app.newrobot.leg.width = 1;
            app.newrobot.leg.thickness = 1;
            app.newrobot.body.height = 1;
            app.newrobot.body.width = 1;
            app.newrobot.name = "newrobot";
        end

        % Value changed function: BodyLengthcmEditField
        function BodyLengthcmEditFieldValueChanged(app, event)
            app.newrobot.body.length = app.BodyLengthcmEditField.Value;
            
        end

        % Value changed function: LegThicknesscmEditField
        function LegThicknesscmEditFieldValueChanged(app, event)
            app.newrobot.leg.thickness = app.LegThicknesscmEditField.Value;
            
        end

        % Value changed function: LegWidthcmEditField
        function LegWidthcmEditFieldValueChanged(app, event)
            app.newrobot.leg.width = app.LegWidthcmEditField.Value;
            
        end

        % Value changed function: LegLengthcmEditField
        function LegLengthcmEditFieldValueChanged(app, event)
            app.newrobot.leg.length = app.LegLengthcmEditField.Value;
            
        end

        % Value changed function: BodyHeightcmEditField
        function BodyHeightcmEditFieldValueChanged(app, event)
            app.newrobot.body.height = app.BodyHeightcmEditField.Value;
            
        end

        % Value changed function: BodyWidthcmEditField
        function BodyWidthcmEditFieldValueChanged(app, event)
            app.newrobot.body.width = app.BodyWidthcmEditField.Value;
            
        end

        % Value changed function: RobotNameEditField
        function RobotNameEditFieldValueChanged(app, event)
            app.newrobot.name = app.RobotNameEditField.Value;
            
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % Bunch of stuff to save a file
            newrobotfilename = sprintf("%s.mat",app.newrobot.name);
            robot = app.newrobot;
            [filename, filepath] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Robot', newrobotfilename);
            if ischar(filename)
                save(fullfile(filepath, filename), 'robot');
            end

            % Update the log
            app.logdbcreator = sprintf("(%s) New database '%s' saved to '%s.mat'.\n",datetime,app.newrobot.name,app.newrobot.name);
            disp(app.logdbcreator)
            externallogupdater(app.mockuppart3,app.logdbcreator);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the new database button again
            app.mockuppart3.NewDatabaseButton.Enable = "off";

            % Delete the new database builder
            delete(app)
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            % Get the image file and store it in our struct
            imgfilename = uigetfile({'*.jpg;*.png;*.gif','Image Files (*.jpg,*.png,*.gif)'},'Select image file...');
            app.newrobot.image = imread(imgfilename);
            app.Image.ImageSource = app.newrobot.image;
        end

        % Value changed function: DescriptionTextArea
        function DescriptionTextAreaValueChanged(app, event)
            app.newrobot.description = app.DescriptionTextArea.Value;
            
        end

        % Value changed function: TotalMasskgEditField
        function TotalMasskgEditFieldValueChanged(app, event)
            app.newrobot.body.totalmass = app.TotalMasskgEditField.Value;
            
        end

        % Value changed function: SingleLegMasskgEditField
        function SingleLegMasskgEditFieldValueChanged(app, event)
            app.newrobot.leg.mass = app.SingleLegMasskgEditField.Value;
            
        end

        % Value changed function: SensorArrayMasskgEditField
        function SensorArrayMasskgEditFieldValueChanged(app, event)
            app.newrobot.body.sensormass = app.SensorArrayMasskgEditField.Value;
            
        end

        % Value changed function: MotorMasskgEditField
        function MotorMasskgEditFieldValueChanged(app, event)
            app.newrobot.motor.mass = app.MotorMasskgEditField.Value;
            
        end

        % Value changed function: CapacitymAhEditField
        function CapacitymAhEditFieldValueChanged(app, event)
            app.newrobot.battery.capacity = app.CapacitymAhEditField.Value;
            
        end

        % Value changed function: BatteryMasskgEditField
        function BatteryMasskgEditFieldValueChanged(app, event)
            app.newrobot.battery.mass = app.BatteryMasskgEditField.Value;
            
        end

        % Value changed function: StallTorqueNmEditField
        function StallTorqueNmEditFieldValueChanged(app, event)
            app.newrobot.motor.stalltorque = app.StallTorqueNmEditField.Value;
            
        end

        % Value changed function: NoloadSpeedrpmEditField
        function NoloadSpeedrpmEditFieldValueChanged(app, event)
            app.newrobot.motor.noloadspeed = app.NoloadSpeedrpmEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NewRobotDatabaseLabel = uilabel(app.UIFigure);
            app.NewRobotDatabaseLabel.Text = 'New Robot Database';
            app.PleaseprovidetherelevantdataLabel = uilabel(app.UIFigure);
            app.PleaseprovidetherelevantdataLabel.Text = 'Please provide the relevant data.';
            app.GeneralPanel = uipanel(app.UIFigure);
            app.GeneralPanel.Title = 'General';
            app.RobotNameEditFieldLabel = uilabel(app.GeneralPanel);
            app.RobotNameEditFieldLabel.Text = 'Robot Name';
            app.RobotNameEditField = uieditfield(app.GeneralPanel, 'text');
            app.RobotNameEditField.ValueChangedFcn = createCallbackFcn(app, @RobotNameEditFieldValueChanged, true);
            app.DescriptionTextAreaLabel = uilabel(app.GeneralPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GeneralPanel);
            app.DescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextAreaValueChanged, true);
            app.SelectFileLabel = uilabel(app.GeneralPanel);
            app.SelectFileLabel.Text = 'Image';
            app.SelectFileButton = uibutton(app.GeneralPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.Image = uiimage(app.GeneralPanel);
            app.PhysicalCharacteristicsPanel = uipanel(app.UIFigure);
            app.PhysicalCharacteristicsPanel.Title = 'Physical Characteristics';
            app.BodyLengthcmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyLengthcmEditFieldLabel.Text = 'Body Length (cm)';
            app.BodyLengthcmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyLengthcmEditFieldValueChanged, true);
            app.BodyLengthcmEditField.Value = 1;
            app.BodyWidthcmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyWidthcmEditFieldLabel.Text = 'Body Width (cm)';
            app.BodyWidthcmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyWidthcmEditFieldValueChanged, true);
            app.BodyWidthcmEditField.Value = 1;
            app.BodyHeightcmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.BodyHeightcmEditFieldLabel.Text = 'Body Height (cm)';
            app.BodyHeightcmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.BodyHeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @BodyHeightcmEditFieldValueChanged, true);
            app.BodyHeightcmEditField.Value = 1;
            app.LegLengthcmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegLengthcmEditFieldLabel.Text = 'Leg Length (cm)';
            app.LegLengthcmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LegLengthcmEditFieldValueChanged, true);
            app.LegLengthcmEditField.Value = 1;
            app.LegWidthcmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegWidthcmEditFieldLabel.Text = 'Leg Width (cm)';
            app.LegWidthcmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LegWidthcmEditFieldValueChanged, true);
            app.LegWidthcmEditField.Value = 1;
            app.LegThicknesscmEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LegThicknesscmEditFieldLabel.Text = 'Leg Thickness (cm)';
            app.LegThicknesscmEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LegThicknesscmEditField.ValueChangedFcn = createCallbackFcn(app, @LegThicknesscmEditFieldValueChanged, true);
            app.LegThicknesscmEditField.Value = 1;
            app.TotalMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass (kg)';
            app.TotalMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.TotalMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @TotalMasskgEditFieldValueChanged, true);
            app.TotalMasskgEditField.Value = 1;
            app.SingleLegMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.SingleLegMasskgEditFieldLabel.Text = 'Single Leg Mass (kg)';
            app.SingleLegMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.SingleLegMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @SingleLegMasskgEditFieldValueChanged, true);
            app.SingleLegMasskgEditField.Value = 1;
            app.SensorArrayMasskgEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.SensorArrayMasskgEditFieldLabel.Text = 'Sensor Array Mass (kg)';
            app.SensorArrayMasskgEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.SensorArrayMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @SensorArrayMasskgEditFieldValueChanged, true);
            app.SensorArrayMasskgEditField.Value = 1;
            app.MotorAttributesPanel = uipanel(app.UIFigure);
            app.MotorAttributesPanel.Title = 'Motor Attributes';
            app.MotorMasskgEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.MotorMasskgEditFieldLabel.Text = 'Motor Mass (kg)';
            app.MotorMasskgEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.MotorMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MotorMasskgEditFieldValueChanged, true);
            app.MotorMasskgEditField.Value = 1;
            app.StallTorqueNmEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.StallTorqueNmEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueNmEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.StallTorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @StallTorqueNmEditFieldValueChanged, true);
            app.StallTorqueNmEditField.Value = 1;
            app.NoloadSpeedrpmEditFieldLabel = uilabel(app.MotorAttributesPanel);
            app.NoloadSpeedrpmEditFieldLabel.Text = 'No-load Speed (rpm)';
            app.NoloadSpeedrpmEditField = uieditfield(app.MotorAttributesPanel, 'numeric');
            app.NoloadSpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @NoloadSpeedrpmEditFieldValueChanged, true);
            app.NoloadSpeedrpmEditField.Value = 1;
            app.BatteryAttributesPanel = uipanel(app.UIFigure);
            app.BatteryAttributesPanel.Title = 'Battery Attributes';
            app.CapacitymAhEditFieldLabel = uilabel(app.BatteryAttributesPanel);
            app.CapacitymAhEditFieldLabel.Text = 'Capacity (mAh)';
            app.CapacitymAhEditField = uieditfield(app.BatteryAttributesPanel, 'numeric');
            app.CapacitymAhEditField.ValueChangedFcn = createCallbackFcn(app, @CapacitymAhEditFieldValueChanged, true);
            app.CapacitymAhEditField.Value = 1;
            app.BatteryMasskgEditFieldLabel = uilabel(app.BatteryAttributesPanel);
            app.BatteryMasskgEditFieldLabel.Text = 'Battery Mass (kg)';
            app.BatteryMasskgEditField = uieditfield(app.BatteryAttributesPanel, 'numeric');
            app.BatteryMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryMasskgEditFieldValueChanged, true);
            app.BatteryMasskgEditField.Value = 1;
            app.SavetoFileButton = uibutton(app.UIFigure, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = databasecreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end