classdef mockuppart3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        RecentProjectsMenu              matlab.ui.container.Menu
        CISCORDUOMenu                   matlab.ui.container.Menu
        MEExcavatorMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        NewDatabaseMenu                 matlab.ui.container.Menu
        LoadDatabaseMenu                matlab.ui.container.Menu
        RecentDatabasesMenu             matlab.ui.container.Menu
        ExportDatabaseMenu              matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OperationManualMenu             matlab.ui.container.Menu
        AboutThisToolMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        DefaultProjectETQuad2023Button  matlab.ui.control.Button
        LoadProjectButton_2             matlab.ui.control.Button
        NewProjectButton                matlab.ui.control.Button
        WelcometoRoboForgeLabel         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DatabaseBrowserTab              matlab.ui.container.Tab
        CreateviewandloadrobotdatabasesLabel  matlab.ui.control.Label
        OverviewPanel                   matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RestoreDefaultsButton           matlab.ui.control.Button
        DetailsPanel                    matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        NextButton                      matlab.ui.control.Button
        SaveSelectionButton             matlab.ui.control.Button
        AvailableDatabasesPanel         matlab.ui.container.Panel
        Tree                            matlab.ui.container.CheckBoxTree
        ExportDatabaseButton            matlab.ui.control.Button
        LoadDatabaseButton              matlab.ui.control.Button
        NewDatabaseButton               matlab.ui.control.Button
        RobotDesignTab                  matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        MassBudgetPanel                 matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ChassisEditField                matlab.ui.control.NumericEditField
        ChassisEditField_2Label         matlab.ui.control.Label
        BatteriesEditField              matlab.ui.control.NumericEditField
        BatteriesEditFieldLabel         matlab.ui.control.Label
        LegsEditField                   matlab.ui.control.NumericEditField
        LegsEditFieldLabel              matlab.ui.control.Label
        MotorsEditField                 matlab.ui.control.NumericEditField
        MotorsEditFieldLabel            matlab.ui.control.Label
        ModelingPanel                   matlab.ui.container.Panel
        RunModelingFunctionsLabel       matlab.ui.control.Label
        SubmitButton_2                  matlab.ui.control.Button
        ScalingRulesPanel               matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        ScalingFactorEditField          matlab.ui.control.NumericEditField
        ScalingFactorLabel              matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        SaveSelectionButton_2           matlab.ui.control.Button
        LogPanel                        matlab.ui.container.Panel
        ExportLogButton                 matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        SketchViewPanel                 matlab.ui.container.Panel
        NoloadSpeedRPMEditField         matlab.ui.control.NumericEditField
        NoloadSpeedRPMEditFieldLabel    matlab.ui.control.Label
        StallTorqueNmEditField          matlab.ui.control.NumericEditField
        StallTorqueNmEditFieldLabel     matlab.ui.control.Label
        BatteryPowerWEditField          matlab.ui.control.NumericEditField
        BatteryPowerWEditFieldLabel     matlab.ui.control.Label
        LegThicknesscmEditField         matlab.ui.control.NumericEditField
        LegThicknesscmEditFieldLabel    matlab.ui.control.Label
        LegWidthcmEditField             matlab.ui.control.NumericEditField
        LegWidthcmEditFieldLabel        matlab.ui.control.Label
        LegLengthcmEditField            matlab.ui.control.NumericEditField
        LegLengthcmEditFieldLabel       matlab.ui.control.Label
        BodyHeightcmEditField           matlab.ui.control.NumericEditField
        BodyHeightcmEditFieldLabel      matlab.ui.control.Label
        BodyWidthcmEditField            matlab.ui.control.NumericEditField
        BodyWidthcmEditFieldLabel       matlab.ui.control.Label
        BodyLengthcmEditField           matlab.ui.control.NumericEditField
        BodyLengthcmEditFieldLabel      matlab.ui.control.Label
        TotalBodyMasskgEditField        matlab.ui.control.NumericEditField
        TotalBodyMassLabel              matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        DeliverablesTab                 matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Batt1Tab                        matlab.ui.container.Tab
        BOMBatteryVoltageText           matlab.ui.control.NumericEditField
        BOMBatteryVoltageLabel          matlab.ui.control.Label
        BOMBatteryWeightText            matlab.ui.control.NumericEditField
        BOMBatteryWeightLabel           matlab.ui.control.Label
        BOMBatteryCapText               matlab.ui.control.NumericEditField
        BOMBatteryCapLabel              matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        Batt2Tab                        matlab.ui.container.Tab
        Batt3Tab                        matlab.ui.container.Tab
        Batt4Tab                        matlab.ui.container.Tab
        Batt5Tab                        matlab.ui.container.Tab
        DeliverablePrevButton           matlab.ui.control.Button
        CopyButton_2                    matlab.ui.control.Image
        SaveValuesButton                matlab.ui.control.Button
        MotorMassText                   matlab.ui.control.NumericEditField
        MotorMassLabel                  matlab.ui.control.Label
        CopyButton_1                    matlab.ui.control.Image
        LinktoAdequateBatteriesButton   matlab.ui.control.Button
        LinktoAdequateMotorsButton      matlab.ui.control.Button
        MotorPowerText                  matlab.ui.control.NumericEditField
        MotorPowerLabel                 matlab.ui.control.Label
        GenerateBillofMaterialsButton   matlab.ui.control.Button
        RobotDesignOLDTab               matlab.ui.container.Tab
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        SubmitandRunButton              matlab.ui.control.Button
        StallTorqueEditField            matlab.ui.control.NumericEditField
        StallTorqueEditFieldLabel       matlab.ui.control.Label
        MotorMassEditField              matlab.ui.control.NumericEditField
        NoloadSpeedEditField            matlab.ui.control.NumericEditField
        NoloadSpeedEditFieldLabel       matlab.ui.control.Label
        MotorMassEditFieldLabel         matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        databasecreator % Dialog app
    end
    
    properties (Access = public)
        MotorMass 
        SimOutputs 
        NoloadSpeed 
        StallTorque
        MotorPower
        testrobot
        editingrobot
        textlog
        BOMFile
        editingshadow
        recentdatabases
        newrecentdatabasemenu
    end
    
    methods (Access = public)
        
        % Get info on what happened in the DB Creator in real time
        function externallogupdater(app,externallog)
            app.textlog = append(app.textlog,externallog);
            app.TextArea_2.Value = app.textlog;
        end
        
        % Handle all the graphing in the Robot Design tab
        function robotgrapher(app,robotinfo)
            % Clear whatever was already in the plot
            cla(app.UIAxes);

            % Plot the robot
            % Robot body 
            body = rectangle(app.UIAxes,'Position',[2, 2, robotinfo.body.length, robotinfo.body.height],'FaceColor','b','Curvature',1);
            % Robot legs
            foreleg = line(app.UIAxes,[7,7], [3,3-robotinfo.leg.length],'linewidth',5,'color','#404040');
            rearleg = line(app.UIAxes,[robotinfo.body.length-3 robotinfo.body.length-3 ...
            ], [3 3-robotinfo.leg.length],'linewidth',5,'color','#404040');

            % Update the side values
            app.LegThicknesscmEditField.Value = robotinfo.leg.thickness;
            app.LegWidthcmEditField.Value = robotinfo.leg.width;
            app.LegLengthcmEditField.Value = robotinfo.leg.length;
            app.BodyHeightcmEditField.Value = robotinfo.body.height;
            app.BodyLengthcmEditField.Value = robotinfo.body.length;
            app.BodyWidthcmEditField.Value = robotinfo.body.width;
            app.TotalBodyMasskgEditField.Value = robotinfo.body.totalmass;
            app.StallTorqueNmEditField.Value = robotinfo.motor.stalltorque;
            app.NoloadSpeedRPMEditField.Value = robotinfo.motor.noloadspeed;


            %Update Mass Budget values
            app.MotorsEditField.Value = (robotinfo.motor.mass/robotinfo.body.totalmass)*100;
            app.LegsEditField.Value = (robotinfo.leg.mass*4/robotinfo.body.totalmass)*100;
            app.BatteriesEditField.Value = (robotinfo.battery.mass*2/robotinfo.body.totalmass)*100;
            app.ChassisEditField.Value = ((robotinfo.body.totalmass - ...
            (robotinfo.motor.mass) - (robotinfo.leg.mass*4) - ...
            (robotinfo.battery.mass*2))/robotinfo.body.totalmass)*100;
        end
        
        function [newModelParam] = dynamicScale(app,modelParam,a_length)
                % This function dynamically scales the parameters in the struct modelParam
                % according to the length scaling factor a_length
                
                % Model Parameter structs should contain the following:
                %   Length
                %   Mass
                %   Stiffness
                %   Damping
                %   Frequency
                %   Gravity
                %   Touch Down Angle
                %   Touch Down Heading
                %   Touch Down Velocity
                
                newModelParam.body.length = modelParam.body.length * a_length;
                newModelParam.body.height = modelParam.body.height * a_length;
                newModelParam.body.width = modelParam.body.width * a_length;
                newModelParam.leg.length = modelParam.leg.length * a_length;
                newModelParam.leg.width = modelParam.leg.width * a_length;
                newModelParam.leg.thickness = modelParam.leg.thickness * a_length;
                newModelParam.body.totalmass = modelParam.body.totalmass * a_length^3;
                newModelParam.leg.mass = modelParam.leg.mass * a_length^3;
                newModelParam.motor.mass = modelParam.motor.mass * a_length^3;

                % Spit back out duplicates of the other fields for now
                % because we're lazy
                newModelParam.name = modelParam.name;
                newModelParam.description = modelParam.description;
                newModelParam.motor.mass = modelParam.motor.mass;
                newModelParam.motor.stalltorque = modelParam.motor.stalltorque;
                newModelParam.motor.noloadspeed = modelParam.motor.noloadspeed;
                newModelParam.battery.capacity = modelParam.battery.capacity;
                newModelParam.battery.mass = modelParam.battery.mass;

%                 newModelParam.stiffness = modelParam.stiffness * a_length^2;
%                 newModelParam.damping = modelParam.damping * a_length^(5/2);
%                 newModelParam.frequency = modelParam.frequency * a_length^(-1/2);
%                 newModelParam.gravity = modelParam.gravity;
%                 newModelParam.tdVel = modelParam.tdVel * a_length^(1/2);
%                 newModelParam.tdHeading= modelParam.tdHeading;
%                 newModelParam.tdAngle= modelParam.tdAngle;
                
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clear out variables in the base workspace
            evalin("base", "clear");
            
            % Initialize the text log
            app.textlog = "";

            %Initialize the BOM
            app.BOMFile = "";

            % Disable the reset button initially
            app.ResetButton.Enable = "off";

            % Initialize recent databases
            app.recentdatabases = ["name", "filepath", "checksum"];
            recentdatabases = app.recentdatabases;

            % Initially hide the Recent Databases menu
            app.RecentDatabasesMenu.Visible = "off";

            % Check to see if the settings file is made
            % If not, make it
            if ~isfile("roboforgesettings.mat")
                % Make the recent databases array
                save("roboforgesettings.mat", "recentdatabases");
                % Eventually, make the recent projects array

                % Once done, load it up
                load("roboforgesettings.mat", "recentdatabases");
            else
                % Otherwise, just load it up
                load("roboforgesettings.mat");
                % If there is more than one row in recentdatabases, add the
                % file to the Recent Databases menu
                if (height(recentdatabases) > 1)
                    % Make the Recent Databases menu appear
                    app.RecentDatabasesMenu.Visible = "on";
                    % Make new menu entry
                    app.newrecentdatabasemenu = uimenu(app.RecentDatabasesMenu);
                    app.newrecentdatabasemenu.Text = recentdatabases(2,1);
                    % Assign load database callback to our new menu
                    app.newrecentdatabasemenu.MenuSelectedFcn = createCallbackFcn(app, @NewRecentDatabaseMenuSelected, true);
                end
            end

            % New function for loading the recent databases
            function NewRecentDatabaseMenuSelected(app, event)
                % This is literally a copy of the regular load database but
                % just without the stuff we don't need because we already know
                % where this file is.
                % Load the database from the file we already know
                recentloadedrobotfilepath = recentdatabases(2,2); % This is taken from the settings file array
                
                robot = "";
                load(recentloadedrobotfilepath); % load the database
                app.editingrobot = robot; % Put the database into a temp struct that we work on
                app.editingshadow = robot; % Put the database into a second struct to reset to later
                % Grab some metadata for later
                robotmeta = dir(recentloadedrobotfilepath);
    
    
                % Grab the names of all the database fields
                editingdatabasenames = string(fieldnames(app.editingrobot));
    
                % Build the description
                robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
                robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
                robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
                robotdetailtext4 = sprintf("Date Modified: %s \n\n",robotmeta.date);
                robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
                app.TextArea.Value = robotdetail;
    
                % Read data from the script and put it in a tree
                firstdatabasenode = uitreenode(app.Tree);
                firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry
    
                % Update the overview picture
                app.Image.ImageSource = app.editingrobot.image;
    
                % Call the robot plotting function
                robotgrapher(app,app.editingrobot);
    
                % Swap over to the database browser
                app.TabGroup.SelectedTab = app.DatabaseBrowserTab;
    
                % Update the log
                % Specify that this one is the default one
                logrecentdataload = sprintf("(%s) Recent database loaded from '%s'.\n",datetime,recentloadedrobotfilepath);
                app.textlog = append(app.textlog,logrecentdataload);
                app.TextArea_2.Value = app.textlog;
                scroll(app.TextArea_2,'bottom');
            end
        end

        % Button pushed function: SubmitButton_2, SubmitandRunButton
        function SubmitandRunButtonPushed(app, event)
            % This is used to run the old DR4 models
            % Create a loading window and display it while this runs
            loadingfig = uifigure("Name","Loading...","WindowStyle","modal");
            loadingfig.Position(3:4) = [500 200];
            simprog = uiprogressdlg(loadingfig,'Title','Robot Architecture','Message','Compiling Mass');
            drawnow;

            % Update the log for the start
            log6 = sprintf("(%s) Simulink analysis initialized.\n",datetime);
            app.textlog = append(app.textlog,log6);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');

            % Take the mass input and send it to Simulink
            simprog.Value = 0.33;
            % If a database has been loaded, use its value. Otherwise, use
            % the Motor mass field in the old tab
            if ~(app.TotalBodyMasskgEditField.Value)
                app.MotorMass = app.MotorMassEditField.Value;
            else
                app.MotorMass = app.TotalBodyMasskgEditField.Value;
            end
            assignin("base","m", app.MotorMass);
            pause(1);

            simprog.Value = 0.66;
            simprog.Message = 'Running System Composer';
            app.SimOutputs = sim("robotModel.slx");

            simprog.Value = 1;
            simprog.Message = 'Returning Results';
            pause(1);
            app.NoloadSpeed = app.SimOutputs.noloadspeed.data;
            app.StallTorque = app.SimOutputs.stalltorque.data;
            app.MotorPower = app.SimOutputs.power.data;
            app.NoloadSpeedEditField.Value = app.NoloadSpeed;
            app.StallTorqueEditField.Value = app.StallTorque;
            app.PowerEditField.Value = app.MotorPower;
            app.NoloadSpeedRPMEditField.Value = app.NoloadSpeed;
            app.StallTorqueNmEditField.Value = app.StallTorque;
            app.BatteryPowerWEditField.Value = app.MotorPower;

            app.MotorPowerText.Value = app.MotorPower;
            app.MotorMassText.Value = app.MotorMass;
            %app.BOMBatteryCapText_1.Value = app.BatteryCap
            %app.BOMBatteryWeightText_1.Value = app.BatteryWeight;
            %app.BOMBatteryVoltageText_1.Value = app.BatteryVoltage;

            close(loadingfig); % Closes the loading bar

            % Update the log
            log7 = sprintf("(%s) Simulink analysis completed.\n",datetime);
            app.textlog = append(app.textlog,log7);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Callback function: LoadDatabaseButton, LoadDatabaseMenu
        function LoadDatabaseMenuSelected(app, event)
            % Load the database from file
            [loadingdatabasefile, loadingdatabasepath] = uigetfile(".mat"); % This gets the filename and path
            % Stop everything if user hits cancel
            if (loadingdatabasefile == 0)
                % Bring the window back into focus
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                return
            end
            % Bring the window back into focus
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Store the full file path
            lastloadedrobotfilepath = append(loadingdatabasepath,loadingdatabasefile);
            load(lastloadedrobotfilepath); % load the database
            app.editingrobot = robot; % Put the database into a temp struct that we work on
            app.editingshadow = robot; % Put the database into a second struct to reset to later
            % Grab some metadata for later
            robotmeta = dir(lastloadedrobotfilepath);

            
            % Check if we already loaded this before
            tempchecksum = Simulink.getFileChecksum(loadingdatabasefile);
            % Make a temp copy of recent databases
            recentdatabases = app.recentdatabases;
            % Is this file in the Recents array?
            if ~(ismember(tempchecksum,recentdatabases))
                % Sort all this data
                tempdatabaseentry = [string(app.editingrobot.name), string(lastloadedrobotfilepath), string(tempchecksum)];
                % Store this database's path and checksum into Recents
                recentdatabases = vertcat(recentdatabases, tempdatabaseentry);
                % Save this to file
                save("roboforgesettings.mat","recentdatabases");
            end


            % Grab the names of all the database fields
            editingdatabasenames = string(fieldnames(app.editingrobot));

            % Build the description
            robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
            robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
            robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
            robotdetailtext4 = sprintf("Date Modified: %s \n\n",robotmeta.date);
            robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
            app.TextArea.Value = robotdetail;

            % Read data from the script and put it in a tree
            firstdatabasenode = uitreenode(app.Tree);
            firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

            % Update the overview picture
            app.Image.ImageSource = app.editingrobot.image;

            % Call the robot plotting function
            robotgrapher(app,app.editingrobot);

            % Update the log
            log1 = sprintf("(%s) Database '%s' loaded.\n",datetime,loadingdatabasefile);
            app.textlog = append(app.textlog,log1);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');

        end

        % Callback function: NewDatabaseButton, NewDatabaseMenu
        function NewDatabaseButtonPushed(app, event)
            % Disable New Database button while dialog is open
            app.NewDatabaseButton.Enable = "off";

            % Let the user make a new robot database

            % Call the database app
            app.databasecreator = databasecreator(app); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.databasecreator)
            delete(app)
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % Call the scaling function
            app.editingrobot = dynamicScale(app,app.editingrobot,app.ScalingFactorEditField.Value); 

            % Call the plotting function
            robotgrapher(app,app.editingrobot);

            % Enable the reset button if we scaled
            if ~(app.ScalingFactorEditField.Value == 1)
                app.ResetButton.Enable = "on";
            end

            % Update the log
            log2 = sprintf("(%s) Scaling factor of %0.2g has been applied.\n",datetime,app.ScalingFactorEditField.Value);
            app.textlog = append(app.textlog,log2);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Button pushed function: ExportLogButton
        function ExportLogButtonPushed(app, event)
            % Save contents of the log to file (in the same folder)
            writematrix(app.textlog,'robotlog.txt');

            % Update the log
            log3 = sprintf("(%s) Log exported to 'robotlog.txt'.\n",datetime);
            app.textlog = append(app.textlog,log3);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Move to the Robot Design tab
            app.TabGroup.SelectedTab = app.RobotDesignTab;
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % Go back to the Database Browser tab
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset the values of the editing database to the loaded one
            app.editingrobot = app.editingshadow;
            app.ScalingFactorEditField.Value = 1;

            % Disable the button
            app.ResetButton.Enable = "off";

            % Call the plotting function
            robotgrapher(app,app.editingrobot);

            % Update the log
            log5 = sprintf("(%s) Robot scaling restored to its initial state.\n",datetime);
            app.textlog = append(app.textlog,log5);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

            % Update the log 
            log0 = sprintf("(%s) New Project Started.\n",datetime);
            app.textlog = append(app.textlog,log0);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            % Move to the Deliverables tab
            app.TabGroup.SelectedTab = app.DeliverablesTab;
        end

        % Image clicked function: CopyButton_1
        function CopyButton_1ImageClicked(app, event)
            motorWatts = 200;
            motorRPM = 3660;
            motorTorque = 500;
            %motorURLVar = 'https://www.maxongroup.us/maxon/view/service_search?query=Motor+%d+W+Motor+%d+rpm+Motor+%d+mNm'

            motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?queryMotor+%d+W+Motor+%d+rpm+Motor+%d+mNm', motorWatts, motorRPM, motorTorque);
            clipboard('copy', motorString);
        end

        % Image clicked function: CopyButton_2
        function CopyButton_2ImageClicked(app, event)
            maxAmpLiPomAh = [430 670 860 1050 1300 1450 1850 2000 2250 2375 2800 3250 ...
                3750 4000 4500 4750 5200 5450 6000 6500 7500 8000 9000 10400 10900 ...
                11000 12000 16000 17000 22000 23000 31000 34000 46000 62000];
            maxAmpsIterate = 1;
            maxampsURLVar = 'https://maxamps.com/collections/lipo-%dmah-packs';

            while (7000 > maxAmpLiPomAh(maxAmpsIterate))
                maxAmpsIterate = maxAmpsIterate + 1;
            end

            searchLB = maxAmpLiPomAh(maxAmpsIterate);
            maxAmpURL = sprintf(maxampsURLVar, searchLB);
            %system(sprintf('explorer https://maxamps.com/collections/lipo-%dmah-packs', searchLB));
            clipboard('copy', maxAmpURL);
        end

        % Button pushed function: LinktoAdequateMotorsButton
        function LinktoAdequateMotorsButtonPushed(app, event)
            motorWatts = 200;
            motorRPM = 3660;
            motorTorque = 500;
            %motorURLVar = 'https://www.maxongroup.us/maxon/view/service_search?query=Motor+%d+W+Motor+%d+rpm+Motor+%d+mNm'

            motorString = sprintf('https://www.maxongroup.us/maxon/view/service_search?queryMotor+%d+W+Motor+%d+rpm+Motor+%d+mNm', motorWatts, motorRPM, motorTorque);
            system(sprintf('explorer %s', motorString));
        end

        % Button pushed function: LinktoAdequateBatteriesButton
        function LinktoAdequateBatteriesButtonPushed(app, event)
            maxAmpLiPomAh = [430 670 860 1050 1300 1450 1850 2000 2250 2375 2800 3250 ...
                3750 4000 4500 4750 5200 5450 6000 6500 7500 8000 9000 10400 10900 ...
                11000 12000 16000 17000 22000 23000 31000 34000 46000 62000];
            maxAmpsIterate = 1;
            maxampsURLVar = 'https://maxamps.com/collections/lipo-%dmah-packs';

            while (7000 > maxAmpLiPomAh(maxAmpsIterate))
                maxAmpsIterate = maxAmpsIterate + 1;
            end

            searchLB = maxAmpLiPomAh(maxAmpsIterate);
            %maxAmpURL = sprintf(maxampsURLVar, searchLB);
            system(sprintf('explorer https://maxamps.com/collections/lipo-%dmah-packs', searchLB));

        end

        % Button pushed function: SaveValuesButton
        function SaveValuesButtonPushed(app, event)
            % Save contents of the deliverables page (in the same folder)
            test1 = 1;
            test2 = 2;
            test3 = 3;
            test4 = 4;
            test5 = 5;
            BOMText = sprintf("Motor Power (W): %d \n" + ...
                "Motor Mass (g): %d \n" + ...
                "Battery Capactiy (mAh): %d \n" + ...
                "Battery Weight (g): %d \n" + ...
                "Battery Voltage (V): %d", test1,test2,test3,test4,test5);
            app.BOMFile = append(app.BOMFile,BOMText);
            writematrix(app.BOMFile,'RobotBOM.txt');
        end

        % Menu selected function: OperationManualMenu
        function OperationManualMenuSelected(app, event)
            system("explorer https://web1.eng.famu.fsu.edu/me/senior_design/2023/team505/files/SDT505OperationManual230303.pdf");
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            system("explorer https://www.ciscor.org/");
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            system("explorer https://eng.famu.fsu.edu/");
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % Don't let the user go back to the start screen
            if (selectedTab == app.StartTab)
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Button pushed function: DeliverablePrevButton
        function DeliverablePrevButtonPushed(app, event)
            % Move to the Robot Design tab
            app.TabGroup.SelectedTab = app.RobotDesignTab;
        end

        % Button pushed function: DefaultProjectETQuad2023Button
        function DefaultProjectETQuad2023ButtonPushed(app, event)
            % This is literally a copy of the regular load database but
            % just without the stuff we don't need because we already know
            % where this file is.
            % Load the database from the file we already know
            loadingdatabasefile = "ETQuad.mat"; % This should already exist in the folder by default
            
            load(loadingdatabasefile); % load the database
            app.editingrobot = robot; % Put the database into a temp struct that we work on
            app.editingshadow = robot; % Put the database into a second struct to reset to later
            % Grab some metadata for later
            robotmeta = dir(loadingdatabasefile);


            % Grab the names of all the database fields
            editingdatabasenames = string(fieldnames(app.editingrobot));

            % Build the description
            robotdetailtext1 = sprintf("Database: %s \n\n",app.editingrobot.name);
            robotdetailtext2 = sprintf("Available Nodes: %s, %s, %s, %s \n\n",editingdatabasenames(1),editingdatabasenames(2),editingdatabasenames(6),editingdatabasenames(7));
            robotdetailtext3 = sprintf("Description: %s \n\n",string(app.editingrobot.description));
            robotdetailtext4 = sprintf("Date Modified: %s \n\n",robotmeta.date);
            robotdetail = append(robotdetailtext1,robotdetailtext2,robotdetailtext3,robotdetailtext4);
            app.TextArea.Value = robotdetail;

            % Read data from the script and put it in a tree
            firstdatabasenode = uitreenode(app.Tree);
            firstdatabasenode.Text = app.editingrobot.name; % Database name should be the 3rd entry

            % Update the overview picture
            app.Image.ImageSource = app.editingrobot.image;

            % Call the robot plotting function
            robotgrapher(app,app.editingrobot);

            % Swap over to the next tab
            app.TabGroup.SelectedTab = app.DatabaseBrowserTab;

            % Update the log
            % Specify that this one is the default one
            logdefaultdataload = sprintf("(%s) Default database '%s' loaded.\n",datetime,loadingdatabasefile);
            app.textlog = append(app.textlog,logdefaultdataload);
            app.TextArea_2.Value = app.textlog;
            scroll(app.TextArea_2,'bottom');
        end

        % Value changed function: BatteryPowerWEditField
        function BatteryPowerWEditFieldValueChanged(app, event)
            value = app.BatteryPowerWEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.Text = 'Open Project';
            app.RecentProjectsMenu = uimenu(app.FileMenu);
            app.RecentProjectsMenu.Text = 'Recent Projects..';
            app.CISCORDUOMenu = uimenu(app.RecentProjectsMenu);
            app.CISCORDUOMenu.Text = 'CISCOR DUO';
            app.MEExcavatorMenu = uimenu(app.RecentProjectsMenu);
            app.MEExcavatorMenu.Text = 'ME Excavator';
            app.SaveProjectMenu = uimenu(app.FileMenu);
            app.SaveProjectMenu.Text = 'Save Project';
            app.NewDatabaseMenu = uimenu(app.FileMenu);
            app.NewDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @NewDatabaseButtonPushed, true);
            app.NewDatabaseMenu.Separator = 'on';
            app.NewDatabaseMenu.Text = 'New Database';
            app.LoadDatabaseMenu = uimenu(app.FileMenu);
            app.LoadDatabaseMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatabaseMenuSelected, true);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.RecentDatabasesMenu = uimenu(app.FileMenu);
            app.RecentDatabasesMenu.Text = 'Recent Databases';
            app.ExportDatabaseMenu = uimenu(app.FileMenu);
            app.ExportDatabaseMenu.Text = 'Export Database';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OperationManualMenu = uimenu(app.HelpMenu);
            app.OperationManualMenu.MenuSelectedFcn = createCallbackFcn(app, @OperationManualMenuSelected, true);
            app.OperationManualMenu.Text = 'Operation Manual';
            app.AboutThisToolMenu = uimenu(app.HelpMenu);
            app.AboutThisToolMenu.Text = 'About This Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.Image2 = uiimage(app.StartTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ciscorlab.jpg');
            app.WelcometoRoboForgeLabel = uilabel(app.StartTab);
            app.WelcometoRoboForgeLabel.Text = 'Welcome to RoboForge';
            app.NewProjectButton = uibutton(app.StartTab, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.LoadProjectButton_2 = uibutton(app.StartTab, 'push');
            app.LoadProjectButton_2.Text = 'Load Project';
            app.DefaultProjectETQuad2023Button = uibutton(app.StartTab, 'push');
            app.DefaultProjectETQuad2023Button.ButtonPushedFcn = createCallbackFcn(app, @DefaultProjectETQuad2023ButtonPushed, true);
            app.DefaultProjectETQuad2023Button.Text = {'Default Project'; '(ETQuad 2023)'};
            app.Image3 = uiimage(app.StartTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'ciscorlogo.png');
            app.Image4 = uiimage(app.StartTab);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'famufsulogo.png');
            app.DatabaseBrowserTab = uitab(app.TabGroup);
            app.DatabaseBrowserTab.Title = 'Database Browser';
            app.AvailableDatabasesPanel = uipanel(app.DatabaseBrowserTab);
            app.AvailableDatabasesPanel.Title = 'Available Databases';
            app.NewDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.NewDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @NewDatabaseButtonPushed, true);
            app.NewDatabaseButton.Text = 'New Database';
            app.LoadDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseMenuSelected, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.ExportDatabaseButton = uibutton(app.AvailableDatabasesPanel, 'push');
            app.ExportDatabaseButton.Text = 'Export Database';
            app.Tree = uitree(app.AvailableDatabasesPanel, 'checkbox');
            app.SaveSelectionButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.SaveSelectionButton.Text = 'Save Selection';
            app.NextButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.DetailsPanel = uipanel(app.DatabaseBrowserTab);
            app.DetailsPanel.Title = 'Details';
            app.TextArea = uitextarea(app.DetailsPanel);
            app.TextArea.Editable = 'off';
            app.RestoreDefaultsButton = uibutton(app.DatabaseBrowserTab, 'push');
            app.RestoreDefaultsButton.Text = 'Restore Defaults';
            app.OverviewPanel = uipanel(app.DatabaseBrowserTab);
            app.OverviewPanel.Title = 'Overview';
            app.Image = uiimage(app.OverviewPanel);
            app.CreateviewandloadrobotdatabasesLabel = uilabel(app.DatabaseBrowserTab);
            app.CreateviewandloadrobotdatabasesLabel.Text = 'Create, view, and load robot databases.';
            app.RobotDesignTab = uitab(app.TabGroup);
            app.RobotDesignTab.Title = 'Robot Design';
            app.SketchViewPanel = uipanel(app.RobotDesignTab);
            app.SketchViewPanel.Title = 'Sketch View';
            app.UIAxes = uiaxes(app.SketchViewPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TotalBodyMassLabel = uilabel(app.SketchViewPanel);
            app.TotalBodyMassLabel.Text = 'Total Body Mass (kg)';
            app.TotalBodyMasskgEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.TotalBodyMasskgEditField.Tooltip = {'The total mass of the robot, including motors, chassis, legs, etc. This does not include any additional payload.'};
            app.BodyLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyLengthcmEditFieldLabel.Text = 'Body Length (cm)';
            app.BodyLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyLengthcmEditField.Tooltip = {'The length of the chassis of the robot from the front to the back.'};
            app.BodyWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyWidthcmEditFieldLabel.Text = 'Body Width (cm)';
            app.BodyWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyWidthcmEditField.Tooltip = {'The width of the chassis of the robot from the left to the right, or vice versa.'};
            app.BodyHeightcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BodyHeightcmEditFieldLabel.Text = 'Body Height (cm)';
            app.BodyHeightcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BodyHeightcmEditField.Tooltip = {'The height of the chassis of the robot from the bottom to the top, or vice versa. This is not the height measured from the floor.'};
            app.LegLengthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegLengthcmEditFieldLabel.Text = 'Leg Length (cm)';
            app.LegLengthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegLengthcmEditField.Tooltip = {'The virtual length of the robot''s leg. For example, this could be the pin to pin distance for a 5-bar or the pin to ground contact distance in a RR leg.'};
            app.LegWidthcmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegWidthcmEditFieldLabel.Text = 'Leg Width (cm)';
            app.LegWidthcmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegWidthcmEditField.Tooltip = {'The width of the leg as measured in the direction of the front of the robot.'};
            app.LegThicknesscmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.LegThicknesscmEditFieldLabel.Text = 'Leg Thickness (cm)';
            app.LegThicknesscmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.LegThicknesscmEditField.Tooltip = {'The thickness of the leg, measured toward the robot body.'};
            app.BatteryPowerWEditFieldLabel = uilabel(app.SketchViewPanel);
            app.BatteryPowerWEditFieldLabel.Text = 'Battery Power (W)';
            app.BatteryPowerWEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.BatteryPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @BatteryPowerWEditFieldValueChanged, true);
            app.BatteryPowerWEditField.Tooltip = {'The battery power required to operate the motors for the desired run time.'};
            app.StallTorqueNmEditFieldLabel = uilabel(app.SketchViewPanel);
            app.StallTorqueNmEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueNmEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.StallTorqueNmEditField.Tooltip = {'The required stall torque speed of the motor and gearbox combination. This assumes one motor at the hip.'};
            app.NoloadSpeedRPMEditFieldLabel = uilabel(app.SketchViewPanel);
            app.NoloadSpeedRPMEditFieldLabel.Text = 'No-load Speed (RPM)';
            app.NoloadSpeedRPMEditField = uieditfield(app.SketchViewPanel, 'numeric');
            app.NoloadSpeedRPMEditField.Tooltip = {'The required no-load speed of the motor and gearbox combination. This assumes one motor at the hip.'};
            app.LogPanel = uipanel(app.RobotDesignTab);
            app.LogPanel.Title = 'Log';
            app.TextArea_2 = uitextarea(app.LogPanel);
            app.TextArea_2.Editable = 'off';
            app.ExportLogButton = uibutton(app.LogPanel, 'push');
            app.ExportLogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogButtonPushed, true);
            app.ExportLogButton.Text = 'Export Log';
            app.SaveSelectionButton_2 = uibutton(app.RobotDesignTab, 'push');
            app.SaveSelectionButton_2.Text = 'Save Selection';
            app.NextButton_2 = uibutton(app.RobotDesignTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.PreviousButton = uibutton(app.RobotDesignTab, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ScalingRulesPanel = uipanel(app.RobotDesignTab);
            app.ScalingRulesPanel.Title = 'Scaling Rules';
            app.ScalingFactorLabel = uilabel(app.ScalingRulesPanel);
            app.ScalingFactorLabel.Text = 'Scaling Factor';
            app.ScalingFactorEditField = uieditfield(app.ScalingRulesPanel, 'numeric');
            app.ScalingFactorEditField.Tooltip = {'This takes an existing robot and scales it using dynamic similarlity. The scaling factor is a length scaling factor.'};
            app.ScalingFactorEditField.Value = 1;
            app.SubmitButton = uibutton(app.ScalingRulesPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ResetButton = uibutton(app.ScalingRulesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ModelingPanel = uipanel(app.RobotDesignTab);
            app.ModelingPanel.Title = 'Modeling';
            app.SubmitButton_2 = uibutton(app.ModelingPanel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitandRunButtonPushed, true);
            app.SubmitButton_2.Text = 'Submit';
            app.RunModelingFunctionsLabel = uilabel(app.ModelingPanel);
            app.RunModelingFunctionsLabel.Text = 'Run Modeling Functions';
            app.MassBudgetPanel = uipanel(app.RobotDesignTab);
            app.MassBudgetPanel.Title = 'Mass Budget';
            app.MotorsEditFieldLabel = uilabel(app.MassBudgetPanel);
            app.MotorsEditFieldLabel.Text = 'Motors';
            app.MotorsEditField = uieditfield(app.MassBudgetPanel, 'numeric');
            app.MotorsEditField.ValueDisplayFormat = '%.1f';
            app.MotorsEditField.Editable = 'off';
            app.LegsEditFieldLabel = uilabel(app.MassBudgetPanel);
            app.LegsEditFieldLabel.Text = 'Legs';
            app.LegsEditField = uieditfield(app.MassBudgetPanel, 'numeric');
            app.LegsEditField.ValueDisplayFormat = '%.1f';
            app.LegsEditField.Editable = 'off';
            app.BatteriesEditFieldLabel = uilabel(app.MassBudgetPanel);
            app.BatteriesEditFieldLabel.Text = 'Batteries';
            app.BatteriesEditField = uieditfield(app.MassBudgetPanel, 'numeric');
            app.BatteriesEditField.ValueDisplayFormat = '%.1f';
            app.BatteriesEditField.Editable = 'off';
            app.ChassisEditField_2Label = uilabel(app.MassBudgetPanel);
            app.ChassisEditField_2Label.Text = 'Chassis';
            app.ChassisEditField = uieditfield(app.MassBudgetPanel, 'numeric');
            app.ChassisEditField.ValueDisplayFormat = '%.1f';
            app.ChassisEditField.Editable = 'off';
            app.Label = uilabel(app.MassBudgetPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.MassBudgetPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.MassBudgetPanel);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.MassBudgetPanel);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.RobotDesignTab);
            app.Label_5.Text = 'View your imported robot, assign paramenters, and scale your robot as needed. Run modeling functions to recieve mass and power requirements for your current scale.';
            app.DeliverablesTab = uitab(app.TabGroup);
            app.DeliverablesTab.Title = 'Deliverables';
            app.GenerateBillofMaterialsButton = uibutton(app.DeliverablesTab, 'push');
            app.GenerateBillofMaterialsButton.Text = 'Generate Bill of Materials';
            app.MotorPowerLabel = uilabel(app.DeliverablesTab);
            app.MotorPowerLabel.Text = 'Motor Power (W)';
            app.MotorPowerText = uieditfield(app.DeliverablesTab, 'numeric');
            app.LinktoAdequateMotorsButton = uibutton(app.DeliverablesTab, 'push');
            app.LinktoAdequateMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @LinktoAdequateMotorsButtonPushed, true);
            app.LinktoAdequateMotorsButton.Text = {'Link to Adequate Motors'; ''};
            app.LinktoAdequateBatteriesButton = uibutton(app.DeliverablesTab, 'push');
            app.LinktoAdequateBatteriesButton.ButtonPushedFcn = createCallbackFcn(app, @LinktoAdequateBatteriesButtonPushed, true);
            app.LinktoAdequateBatteriesButton.Text = {'Link to Adequate Batteries'; ''};
            app.CopyButton_1 = uiimage(app.DeliverablesTab);
            app.CopyButton_1.ImageClickedFcn = createCallbackFcn(app, @CopyButton_1ImageClicked, true);
            app.CopyButton_1.ImageSource = 'copyLogo.png';
            app.MotorMassLabel = uilabel(app.DeliverablesTab);
            app.MotorMassLabel.Text = 'Motor Mass (g)';
            app.MotorMassText = uieditfield(app.DeliverablesTab, 'numeric');
            app.SaveValuesButton = uibutton(app.DeliverablesTab, 'push');
            app.SaveValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveValuesButtonPushed, true);
            app.SaveValuesButton.Text = 'Save Values';
            app.CopyButton_2 = uiimage(app.DeliverablesTab);
            app.CopyButton_2.ImageClickedFcn = createCallbackFcn(app, @CopyButton_2ImageClicked, true);
            app.CopyButton_2.ImageSource = 'copyLogo.png';
            app.DeliverablePrevButton = uibutton(app.DeliverablesTab, 'push');
            app.DeliverablePrevButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverablePrevButtonPushed, true);
            app.DeliverablePrevButton.Text = 'Previous';
            app.TabGroup2 = uitabgroup(app.DeliverablesTab);
            app.Batt1Tab = uitab(app.TabGroup2);
            app.Batt1Tab.Title = 'Batt. 1';
            app.BOMBatteryCapLabel = uilabel(app.Batt1Tab);
            app.BOMBatteryCapLabel.Text = 'Battery Capacity (mAh)';
            app.BOMBatteryCapText = uieditfield(app.Batt1Tab, 'numeric');
            app.BOMBatteryWeightLabel = uilabel(app.Batt1Tab);
            app.BOMBatteryWeightLabel.Text = 'Battery Weight (g)';
            app.BOMBatteryWeightText = uieditfield(app.Batt1Tab, 'numeric');
            app.BOMBatteryVoltageLabel = uilabel(app.Batt1Tab);
            app.BOMBatteryVoltageLabel.Text = 'Battery Voltage (V)';
            app.BOMBatteryVoltageText = uieditfield(app.Batt1Tab, 'numeric');
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Batt2Tab = uitab(app.TabGroup2);
            app.Batt2Tab.Title = 'Batt. 2';
            app.Batt3Tab = uitab(app.TabGroup2);
            app.Batt3Tab.Title = 'Batt. 3';
            app.Batt4Tab = uitab(app.TabGroup2);
            app.Batt4Tab.Title = 'Batt. 4';
            app.Batt5Tab = uitab(app.TabGroup2);
            app.Batt5Tab.Title = 'Batt. 5';
            app.RobotDesignOLDTab = uitab(app.TabGroup);
            app.RobotDesignOLDTab.Title = 'Robot Design (OLD)';
            app.ResultsLabel = uilabel(app.RobotDesignOLDTab);
            app.ResultsLabel.Text = 'Results';
            app.MotorMassEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.MotorMassEditFieldLabel.Text = 'Motor Mass (kg)';
            app.NoloadSpeedEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.NoloadSpeedEditFieldLabel.Text = 'No-load Speed (RPM)';
            app.NoloadSpeedEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.NoloadSpeedEditField.Editable = 'off';
            app.MotorMassEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.MotorMassEditField.Limits = [1e-06 Inf];
            app.MotorMassEditField.Value = 1;
            app.StallTorqueEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.StallTorqueEditFieldLabel.Text = 'Stall Torque (Nm)';
            app.StallTorqueEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.StallTorqueEditField.Editable = 'off';
            app.SubmitandRunButton = uibutton(app.RobotDesignOLDTab, 'push');
            app.SubmitandRunButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitandRunButtonPushed, true);
            app.SubmitandRunButton.Text = 'Submit and Run';
            app.PowerEditFieldLabel = uilabel(app.RobotDesignOLDTab);
            app.PowerEditFieldLabel.Text = 'Power (W)';
            app.PowerEditField = uieditfield(app.RobotDesignOLDTab, 'numeric');
            app.PowerEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mockuppart3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end